classdef VULCAN_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VULCANGUIPanel                matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        DrillTab                      matlab.ui.container.Tab
        DrillControlsButtonGroup      matlab.ui.container.ButtonGroup
        STARTButton                   matlab.ui.control.ToggleButton
        STOPButton                    matlab.ui.control.ToggleButton
        ToolChangeButton              matlab.ui.control.ToggleButton
        ToolChangeButtonGroup         matlab.ui.container.ButtonGroup
        Bit1Button                    matlab.ui.control.ToggleButton
        Bit2Button                    matlab.ui.control.ToggleButton
        Bit3Button                    matlab.ui.control.ToggleButton
        Bit4Button                    matlab.ui.control.ToggleButton
        UITable                       matlab.ui.control.Table
        AmperageAGaugeLabel           matlab.ui.control.Label
        AmperageAGauge                matlab.ui.control.SemicircularGauge
        UIAxes                        matlab.ui.control.UIAxes
        HeatProbeTab                  matlab.ui.container.Tab
        HeatProbeControlsButtonGroup  matlab.ui.container.ButtonGroup
        STARTButton_2                 matlab.ui.control.ToggleButton
        STOPButton_2                  matlab.ui.control.ToggleButton
        ToolChangeButton_2            matlab.ui.control.ToggleButton
        PumpTab                       matlab.ui.container.Tab
        PumpControlButtonGroup        matlab.ui.container.ButtonGroup
        STARTButton_3                 matlab.ui.control.ToggleButton
        STOPButton_3                  matlab.ui.control.ToggleButton
        ValvesTab                     matlab.ui.container.Tab
        ValveControlsButtonGroup      matlab.ui.container.ButtonGroup
        Valve1Button                  matlab.ui.control.ToggleButton
        Valve2Button                  matlab.ui.control.ToggleButton
        Valve3Button                  matlab.ui.control.ToggleButton
        STOPButton_4                  matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        a; % Serial connection
        h; %digital core live plot
        
        timeLogs;
        AmperageLogs;
        TimeSecs;
        Amp;
        
        ToolChange;
        s;
        n; %digital core counter
        t;
        data; %array for getting arduino sensor data
    end
    
    properties (Access = public)
        digital_core_data; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.t = table;
           app.n = 0;
           
           %serial communication
           app.a = serialport('COM7',9600);
           configureTerminator(app.a,"CR/LF",255);
           
           %digital core setup
           app.h = animatedline(app.UIAxes);
           app.digital_core_data = cell(100,2); %empty cell array, where 1st column is time started, second is arrays for digital core.
           
           %hide certain buttons on startup
           app.ToolChangeButtonGroup.Visible = 'off'; 
        end

        % Selection changed function: DrillControlsButtonGroup
        function DrillControlsButtonGroupSelectionChanged(app, event)

            app.ToolChange = 0;
            
            if(app.STARTButton.Value)
                %write to file
                fileID = fopen('DigitalCore.txt','w');
                fprintf(fileID,'%12s %15s %12s %12s\n','Duration(s)','Distance(steps)','Force(N)','Amperage(A)');

                app.n = app.n+1; %count for digital core tracking
                app.ToolChangeButton.Visible = 'off';
                app.ToolChangeButtonGroup.Visible = 'off';
                writeline(app.a,'1');%Start moving downwards!
                
                clearpoints(app.h); %Clear points on plot each time digital core starts
                startTime = datetime; %Establish start-time for this digital core
                    
                %digital core
                flush(app.a,"input"); %clears serial data before starting loop
                                      %this assumes we don't care about
                                      %data when stop button is pressed
                %NOTE: sampling frequency is handled in arduino code
                
                while(app.STARTButton.Value)
                    dataString = readline(app.a); %gets data from arduino
                    app.data = str2num(dataString); %Puts data from arduino in array
                    addpoints(app.h, app.data(1),app.data(2))%Add points to animation
                            
                    %Get current time    
                    duration = seconds(datetime - startTime); %Current time - start time]
                    nr = {duration, app.data(3),app.data(2)}; %Could do starttime instead of n
                    app.UITable.Data = [app.t;nr];
                    app.t = app.UITable.Data; %table stores all previous data - Make text file?
                    fprintf(fileID,'%12.3f %15.3f %12.3f %12.3f\n',duration,app.data(1),app.data(2),app.data(3));
                    
                    app.AmperageAGauge.Value = app.data(3);
                end
                
                fclose(fileID);
            end   
            
            if(app.STOPButton.Value)
                app.ToolChangeButton.Visible = 'on';
                app.ToolChangeButtonGroup.Visible = 'off';
                writeline(app.a,'30'); %breaks drill loop
            end
            
            if(app.ToolChangeButton.Value)
                app.ToolChangeButtonGroup.Visible = 'on';
                writeline(app.a,'2'); %retract until stopped
            end
            
        end

        % Callback function
        function SliderValueChanged(app, event)
    
        end

        % Callback function
        function SliderValueChanged2(app, event)
            
        end

        % Callback function
        function SliderValueChanging(app, event)
        
        end

        % Selection changed function: ToolChangeButtonGroup
        function ToolChangeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ToolChangeButtonGroup.SelectedObject;
            if(app.Bit1Button.Value)
                writeline(app.a,'3');
            elseif(app.Bit2Button.Value)
                writeline(app.a,'4');
            elseif(app.Bit3Button.Value)
                writeline(app.a,'5');
            else
                writeline(app.a,'6');
            end
        end

        % Selection changed function: HeatProbeControlsButtonGroup
        function HeatProbeControlsButtonGroupSelectionChanged(app, event)
            if(app.STARTButton_2.Value)
                writeline(app.a, '7'); %Turn off z motor
            end  
            
            if(app.STOPButton_2.Value)
                writeline(app.a,'10');
            end
        end

        % Selection changed function: PumpControlButtonGroup
        function PumpControlButtonGroupSelectionChanged(app, event)
            if(app.STARTButton_3.Value)
                writeline(app.a,'8');
            end
            
            if(app.STOPButton_3.Value)
                writeline(app.a,'10');
            end
            
        end

        % Selection changed function: ValveControlsButtonGroup
        function ValveControlsButtonGroupSelectionChanged(app, event)
            if(app.Valve1Button.Value)
                writeline(app.a,'9');
            end
            
            if(app.STOPButton_4.Value)
                writeline(app.a,'10');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VULCANGUIPanel = uipanel(app.UIFigure);
            app.VULCANGUIPanel.Title = 'VULCAN GUI';
            app.TabGroup = uitabgroup(app.VULCANGUIPanel);
            app.DrillTab = uitab(app.TabGroup);
            app.DrillTab.Title = 'Drill';
            app.DrillControlsButtonGroup = uibuttongroup(app.DrillTab);
            app.DrillControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrillControlsButtonGroupSelectionChanged, true);
            app.DrillControlsButtonGroup.Title = 'Drill Controls';
            app.STARTButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.STARTButton.Text = 'START';
            app.STOPButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.STOPButton.Text = 'STOP';
            app.ToolChangeButton = uitogglebutton(app.DrillControlsButtonGroup);
            app.ToolChangeButton.Text = 'Tool Change';
            app.ToolChangeButton.Value = true;
            app.ToolChangeButtonGroup = uibuttongroup(app.DrillTab);
            app.ToolChangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolChangeButtonGroupSelectionChanged, true);
            app.ToolChangeButtonGroup.Title = 'Tool Change';
            app.Bit1Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit1Button.Text = 'Bit 1';
            app.Bit2Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit2Button.Text = 'Bit 2';
            app.Bit3Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit3Button.Text = 'Bit 3';
            app.Bit4Button = uitogglebutton(app.ToolChangeButtonGroup);
            app.Bit4Button.Text = 'Bit 4';
            app.Bit4Button.Value = true;
            app.UITable = uitable(app.DrillTab);
            app.UITable.ColumnName = {'Time (s)'; 'Current (A)'; 'WOB (N)'};
            app.UITable.RowName = {};
            app.AmperageAGaugeLabel = uilabel(app.DrillTab);
            app.AmperageAGaugeLabel.Text = 'Amperage (A)';
            app.AmperageAGauge = uigauge(app.DrillTab, 'semicircular');
            app.AmperageAGauge.Limits = [0 10];
            app.AmperageAGauge.MajorTicks = [1 3 5 7 9];
            app.UIAxes = uiaxes(app.DrillTab);
            title(app.UIAxes, 'Digital Core Collection')
            xlabel(app.UIAxes, 'Vertical Distance (Motor steps)')
            ylabel(app.UIAxes, 'Force (N)')
            app.HeatProbeTab = uitab(app.TabGroup);
            app.HeatProbeTab.Title = 'Heat Probe';
            app.HeatProbeControlsButtonGroup = uibuttongroup(app.HeatProbeTab);
            app.HeatProbeControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HeatProbeControlsButtonGroupSelectionChanged, true);
            app.HeatProbeControlsButtonGroup.Title = 'Heat Probe Controls';
            app.STARTButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.STARTButton_2.Text = 'START';
            app.STARTButton_2.Value = true;
            app.STOPButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.STOPButton_2.Text = 'STOP';
            app.ToolChangeButton_2 = uitogglebutton(app.HeatProbeControlsButtonGroup);
            app.ToolChangeButton_2.Text = 'Tool Change';
            app.PumpTab = uitab(app.TabGroup);
            app.PumpTab.Title = 'Pump';
            app.PumpControlButtonGroup = uibuttongroup(app.PumpTab);
            app.PumpControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PumpControlButtonGroupSelectionChanged, true);
            app.PumpControlButtonGroup.Title = 'Pump Control';
            app.STARTButton_3 = uitogglebutton(app.PumpControlButtonGroup);
            app.STARTButton_3.Text = 'START';
            app.STARTButton_3.Value = true;
            app.STOPButton_3 = uitogglebutton(app.PumpControlButtonGroup);
            app.STOPButton_3.Text = 'STOP';
            app.ValvesTab = uitab(app.TabGroup);
            app.ValvesTab.Title = 'Valves';
            app.ValveControlsButtonGroup = uibuttongroup(app.ValvesTab);
            app.ValveControlsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ValveControlsButtonGroupSelectionChanged, true);
            app.ValveControlsButtonGroup.Title = 'Valve Controls';
            app.Valve1Button = uitogglebutton(app.ValveControlsButtonGroup);
            app.Valve1Button.Text = 'Valve 1';
            app.Valve1Button.Value = true;
            app.Valve2Button = uitogglebutton(app.ValveControlsButtonGroup);
            app.Valve2Button.Text = 'Valve 2';
            app.Valve3Button = uitogglebutton(app.ValveControlsButtonGroup);
            app.Valve3Button.Text = 'Valve 3';
            app.STOPButton_4 = uitogglebutton(app.ValveControlsButtonGroup);
            app.STOPButton_4.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VULCAN_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end